classdef interfaz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        n4                              matlab.ui.control.Label
        n3                              matlab.ui.control.Label
        n2                              matlab.ui.control.Label
        n1                              matlab.ui.control.Label
        gracias                         matlab.ui.control.Label
        mostrarResultado                matlab.ui.control.Label
        Nintervalos                     matlab.ui.control.NumericEditField
        IngreseelnmerodeintervalosEditFieldLabel  matlab.ui.control.Label
        LMsuperior                      matlab.ui.control.NumericEditField
        IngreseellimitesuperiorLabel    matlab.ui.control.Label
        LMinferior                      matlab.ui.control.NumericEditField
        IngreseellimiteinferiorEditFieldLabel  matlab.ui.control.Label
        funcion                         matlab.ui.control.EditField
        IngresarelvalordefxEditFieldLabel  matlab.ui.control.Label
        metodos                         matlab.ui.control.DropDown
        AlgoritmosoMtodosDropDownLabel  matlab.ui.control.Label
        calcularBoton                   matlab.ui.control.Button
        Panel3                          matlab.ui.container.Panel
        nombres                         matlab.ui.control.Label
        IntegrantesLabel                matlab.ui.control.Label
        escuela                         matlab.ui.control.Label
        facultad                        matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        logo                            matlab.ui.control.Image
        universidad                     matlab.ui.control.Label
        Panel2                          matlab.ui.container.Panel
        Panel4                          matlab.ui.container.Panel
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: calcularBoton
        function calcularBotonPushed(app, event)
            value = app.metodos.Value;
            syms x;
            fx = str2sym(app.funcion.Value);
            a = app.LMinferior.Value;
            b = app.LMsuperior.Value;
            n = app.Nintervalos.Value;

            switch value
                case 1
                    %Cerradas de Newton
                    f=inline(fx);
                    %%%Trapecio
                    area=0;
                    deltax=(b-a)/n;
                    x0=a;    
                    for i=1:n
                        x1=a+i*deltax;
                        area=area+deltax*(f(x0)+f(x1))/2;
                        x0=x1;
                    
                    end;
                    n1=area;
                    app.n1.Visible = "on";
                    app.n1.Text = "Trapecio: " + num2str(n1);
                    %%%%%%Simpson 1/3
                    area1=0;
                    area2=0;
                    n=16;
                    deltax=(b-a)/n;
                    suma=0;
                    i=1;
                    while i<n
                        x1=a+i*deltax;
                        x2=a+(i+1)*deltax;
                        suma=suma+1/3*deltax*(f(x0)+4*f(x1)+f(x2));
                        i=i+2;
                        x0=x2;
                    end
                    n2=suma;
                    app.n2.Visible = "on";
                    app.n2.Text = "Simpson 1/3: " + num2str(n2);
                    %%%%%Simpson 3/8
                    n=15;
                    deltax=(b-a)/n;
                    suma=0;
                    i=1;
                    while i<n
                        x1=a+i*deltax;
                        x2=a+(i+1)*deltax;
                        x3=a+(i+2)*deltax;
                        suma=suma+3/8*deltax*(f(x0)+3*f(x1)+3*f(x2)+f(x3));
                        i=i+3;
                        x0=x3;
                    end
                    n3=suma;
                    app.n3.Visible = "on";
                    app.n3.Text = "Simpson 3/8: " + num2str(n3);
                    %%%%% n=4
                    n=16;
                    deltax=(b-a)/n;
                    suma=0;
                    i=1;
                    while i<n
                        x1=a+i*deltax;
                        x2=a+(i+1)*deltax;
                        x3=a+(i+2)*deltax;
                        x4=a+(i+3)*deltax;
                        suma=suma+2/45*deltax*(7*f(x0)+32*f(x1)+12*f(x2)+32*f(x3)+7*f(x4));
                        i=i+4;
                        x0=x4;
                    end
                    n4 = suma;

                    app.n4.Visible = "on";
                    app.n4.Text = "N4: " + num2str(n4);
                    
                    resultado = "Los resultado de la intrgración son: ";
                    
                case 2
                    %Abiertas de Newton
                    f=inline(fx);
                    %newton cotes abiertas
                    %n=0
                    n=0;
                    h=(b-a)/(n+2);
                    n0=2*h*f((a+b)/2)
                    app.n1.Visible = "on";
                    app.n1.Text = "N1 es igual a: " + num2str(n0);
                    %n=1
                    n=1;
                    h=(b-a)/(n+2);
                    n1=3*h/2*(f(a+(b-a)/3)+f(a+(b-a)/3*2))
                    app.n2.Visible = "on";
                    app.n2.Text = "N2 es igual a: " + num2str(n1);
                    %n=2
                    n=2;
                    h=(b-a)/(n+2);
                    n2=4*h/3*(2*f(a+(b-a)/4)-f(a+(b-a)/4*2)+2*f(a+(b-a)/4*3))
                    app.n3.Visible = "on";
                    app.n3.Text = "N3 es igual a: " + num2str(n2);
                    %n=3
                    n=3;
                    h=(b-a)/(n+2);
                    n3=5*h/24*(11*f(a+(b-a)/5)+f(a+(b-a)/5*2)+f(a+(b-a)/5*3)+11*f(a+(b-a)/5*4))
                    app.n4.Visible = "on";
                    app.n4.Text = "N4 es igual a: " + num2str(n3);

                    resultado = "Los resultados de la intrgración son: ";
                case 3
                    %Compuesta Trapezoidal
                    f=inline(fx);
                    %PASO1
                    h=(b-a)/n;
                    %PASO2
                    XI0=f(a)+f(b);
                    XI1=0; 
                    %PASO3
                    for i=1:n-1
                    %PASO4
                        X=a+i*h;
                        XI1=XI1+f(X);
                    end
                    %PASO5
                    XI=h*(XI0+2*XI1)/2;
                    %PASO6
                    XI

                    resultadofinal = XI;

                    resultado = "El resultado de la intrgración es: " + num2str(resultadofinal);

                case 4
                    %Compuesta Simpson

                    f=inline(fx);
                    %PASO1
                    h=(b-a)/n;
                    %PASO2
                    XI0=f(a)+f(b);
                    XI1=0; 
                    XI2=0;
                    %PASO3
                    for i=1:n-1
                    %PASO4
                        X=a+i*h;
                    %PASO5
                        if(mod(i,2)==0)
                            XI2=XI2+f(X);
                            XI1=XI1+f(X);
                        end
                    end
                    %PASO6
                    XI=h*(XI0+2*XI2+4*XI1)/3;
                    %PASO7
                    XI

                    resultadofinal = XI;

                    resultado = "El resultado de la intrgración es: " + num2str(resultadofinal);

                case 5
                    %Integracion Romberg
                    f=inline(fx);
                    sumatoria=0;
                    %paso1
                    h=b-a;
                    R=zeros(2,n+1);
                    R(1,1)=h/2*(f(a)+f(b));
                    %paso2
                    fprintf('\nRomberg Tabla:\n');
                     fprintf('\n %11.8f\n\n', R(1,1));
                    %paso3
                    for i=2:n
                        %paso4
                        for k=1:2^(i-2)
                            sumatoria=sumatoria+f(a+h*(k-0.5));
                        end
                        R(2,1)=(R(1,1)+h*sumatoria)/2;%aproximacion mediante el metodo Traspezoidal
                        %paso5
                        for j=2:i
                            R(2,j)=R(2,j-1)+(R(2,j-1)-R(1,j-1))/((4^(j-1))-1);%Extrapolacion
                        end
                        %paso6
                        for j=1:i
                            fprintf(' %11.8f',R(2,j));
                        end
                        fprintf('\n\n');
                        %paso7
                        h=h/2;
                        %paso8
                        for j=1:i
                            R(1,j)=R(2,j);%Actualiza la fila 1 de R
                        end
                    end
                    resultadofinal = R(2,j);

                    resultado = "El resultado de la intrgración es: " + num2str(resultadofinal);
            end

            app.mostrarResultado.Text = resultado;
        end

        % Value changed function: metodos
        function metodosValueChanged(app, event)
            value = app.metodos.Value;
            switch value
                case 1
                    app.gracias.Visible = "off";
                    app.IngreseelnmerodeintervalosEditFieldLabel.Visible = "off";
                    app.Nintervalos.Visible = "off";
                    app.n1.Visible = "off";
                    app.n2.Visible = "off";
                    app.n3.Visible = "off";
                    app.n4.Visible = "off";
                case 2
                    app.gracias.Visible = "off";
                    app.IngreseelnmerodeintervalosEditFieldLabel.Visible = "off";
                    app.Nintervalos.Visible = "off";
                    app.n1.Visible = "off";
                    app.n2.Visible = "off";
                    app.n3.Visible = "off";
                    app.n4.Visible = "off";
                case 3
                    app.gracias.Visible = "on";
                    app.IngreseelnmerodeintervalosEditFieldLabel.Visible = "on";
                    app.Nintervalos.Visible = "on";
                    app.n1.Visible = "off";
                    app.n2.Visible = "off";
                    app.n3.Visible = "off";
                    app.n4.Visible = "off";
                case 4
                    app.gracias.Visible = "on";
                    app.IngreseelnmerodeintervalosEditFieldLabel.Visible = "on";
                    app.Nintervalos.Visible = "on";
                    app.n1.Visible = "off";
                    app.n2.Visible = "off";
                    app.n3.Visible = "off";
                    app.n4.Visible = "off";
                case 5
                    app.gracias.Visible = "on";
                    app.IngreseelnmerodeintervalosEditFieldLabel.Visible = "on";
                    app.Nintervalos.Visible = "on";
                    app.n1.Visible = "off";
                    app.n2.Visible = "off";
                    app.n3.Visible = "off";
                    app.n4.Visible = "off";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel4 = uipanel(app.UIFigure);
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel = uipanel(app.UIFigure);
            app.universidad = uilabel(app.Panel);
            app.universidad.Text = {'UNIVERSIDAD NACIONAL DE '; 'TRUJILLO'};
            app.logo = uiimage(app.Panel);
            app.logo.ImageSource = fullfile(pathToMLAPP, 'logo-UNT.png');
            app.Panel3 = uipanel(app.UIFigure);
            app.facultad = uilabel(app.Panel3);
            app.facultad.Text = 'FACULTAD DE CIENCIAS FÍSICAS Y MATEMÁTICAS';
            app.escuela = uilabel(app.Panel3);
            app.escuela.Text = 'ESCULA DE INFORMÁTICA';
            app.IntegrantesLabel = uilabel(app.Panel3);
            app.IntegrantesLabel.Text = 'Integrantes:';
            app.nombres = uilabel(app.Panel3);
            app.nombres.Text = {'-  Ascencios Carranza Adrian'; '-  Córdova Sáenz Enrique'; '-  Mendez Quiñones Ángel'; '-  Mercado Cueva Esteban'; '-  Sanchez Chunga Alem'; '-  Valdez Reyes José'};
            app.calcularBoton = uibutton(app.UIFigure, 'push');
            app.calcularBoton.ButtonPushedFcn = createCallbackFcn(app, @calcularBotonPushed, true);
            app.calcularBoton.Text = 'CALCULAR';
            app.AlgoritmosoMtodosDropDownLabel = uilabel(app.UIFigure);
            app.AlgoritmosoMtodosDropDownLabel.Text = 'Algoritmos o Métodos:';
            app.metodos = uidropdown(app.UIFigure);
            app.metodos.Items = {'Cerradas de Newton', 'Abiertas de Newton', 'Compuesta Trapezoidal', 'Compuesta Simpson', 'Integración Romberg'};
            app.metodos.ItemsData = [1 2 3 4 5];
            app.metodos.ValueChangedFcn = createCallbackFcn(app, @metodosValueChanged, true);
            app.metodos.Value = 4;
            app.IngresarelvalordefxEditFieldLabel = uilabel(app.UIFigure);
            app.IngresarelvalordefxEditFieldLabel.Text = 'Ingresar el valor de fx():';
            app.funcion = uieditfield(app.UIFigure, 'text');
            app.funcion.Placeholder = 'Ejemplo: cos(x)';
            app.funcion.Value = 'cos(x)';
            app.IngreseellimiteinferiorEditFieldLabel = uilabel(app.UIFigure);
            app.IngreseellimiteinferiorEditFieldLabel.Text = 'Ingrese el limite inferior:';
            app.LMinferior = uieditfield(app.UIFigure, 'numeric');
            app.LMinferior.Value = 1;
            app.IngreseellimitesuperiorLabel = uilabel(app.UIFigure);
            app.IngreseellimitesuperiorLabel.Text = 'Ingrese el limite superior:';
            app.LMsuperior = uieditfield(app.UIFigure, 'numeric');
            app.LMsuperior.Value = 9;
            app.IngreseelnmerodeintervalosEditFieldLabel = uilabel(app.UIFigure);
            app.IngreseelnmerodeintervalosEditFieldLabel.Text = 'Ingrese el número de intervalos:';
            app.Nintervalos = uieditfield(app.UIFigure, 'numeric');
            app.Nintervalos.Value = 5;
            app.mostrarResultado = uilabel(app.UIFigure);
            app.mostrarResultado.Text = 'Aquí se mostrará el resultado del calculo realizado';
            app.gracias = uilabel(app.UIFigure);
            app.gracias.Text = '¡GRACIAS!';
            app.n1 = uilabel(app.UIFigure);
            app.n1.Visible = 'off';
            app.n2 = uilabel(app.UIFigure);
            app.n2.Visible = 'off';
            app.n3 = uilabel(app.UIFigure);
            app.n3.Visible = 'off';
            app.n4 = uilabel(app.UIFigure);
            app.n4.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interfaz

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end